classdef sesupOptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SystemOptionsUIFigure         matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        CONFIGURATIONLabel            matlab.ui.control.Label
        OVERVIEWLabel                 matlab.ui.control.Label
        ConfigPanel                   matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        InitialconditionsLabel        matlab.ui.control.Label
        InitialoutputEditFieldLabel   matlab.ui.control.Label
        InitialoutputEditField        matlab.ui.control.EditField
        InitialstateEditFieldLabel    matlab.ui.control.Label
        InitialstateEditField         matlab.ui.control.EditField
        InitialcovarianceEditFieldLabel  matlab.ui.control.Label
        InitialcovarianceEditField    matlab.ui.control.EditField
        NoiseLabel                    matlab.ui.control.Label
        ProcessnoisecovarianceEditFieldLabel  matlab.ui.control.Label
        ProcessnoisecovarianceEditField  matlab.ui.control.EditField
        MeasurementnoisecovarianceEditFieldLabel  matlab.ui.control.Label
        MeasurementnoisecovarianceEditField  matlab.ui.control.EditField
        ParameterLabel                matlab.ui.control.Label
        LowerboundEditFieldLabel      matlab.ui.control.Label
        LowerboundEditField           matlab.ui.control.EditField
        UpperboundEditFieldLabel      matlab.ui.control.Label
        UpperboundEditField           matlab.ui.control.EditField
        ExpectedvalueEditFieldLabel   matlab.ui.control.Label
        ExpectedvalueEditField        matlab.ui.control.EditField
        SamplingperiodEditFieldLabel  matlab.ui.control.Label
        SamplingperiodEditField       matlab.ui.control.NumericEditField
        SaveButton                    matlab.ui.control.Button
        OverviewPanel                 matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        dfddxTextAreaLabel            matlab.ui.control.Label
        dfddxTextArea                 matlab.ui.control.TextArea
        OPStateEditFieldLabel         matlab.ui.control.Label
        OPStateEditField              matlab.ui.control.EditField
        DimensionsstateparameterinputoutputLabel  matlab.ui.control.Label
        DimensionsTextArea            matlab.ui.control.TextArea
        dfddthTextAreaLabel           matlab.ui.control.Label
        dfddthTextArea                matlab.ui.control.TextArea
        dfddwTextAreaLabel            matlab.ui.control.Label
        dfddwTextArea                 matlab.ui.control.TextArea
        OPParameterEditFieldLabel     matlab.ui.control.Label
        OPParameterEditField          matlab.ui.control.EditField
        OPInputEditFieldLabel         matlab.ui.control.Label
        OPInputEditField              matlab.ui.control.EditField
        OPProcNoiseEditFieldLabel     matlab.ui.control.Label
        OPProcNoiseEditField          matlab.ui.control.EditField
        dgdxTextAreaLabel             matlab.ui.control.Label
        dgdxTextArea                  matlab.ui.control.TextArea
        dgdthTextAreaLabel            matlab.ui.control.Label
        dgdthTextArea                 matlab.ui.control.TextArea
        RefreshJacobiansButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        sysopt
        x_op
        th_op
        w_op
        u_op
        e_op
        EnableTs
    end
    
    methods (Access = private)
        
        function initializeFields(app,sysdef,sysopt)
            % Initialize Samplig period 
            if isa(sysdef,'nlcm') || isa(sysdef,'som2nlcm')
                app.SamplingperiodEditField.Enable = true;
%                 app.SamplingperiodEditField.Visible = true;
                app.SamplingperiodEditField.Value = sysopt.Ts;
            else
                app.SamplingperiodEditField.Enable = false;
%                 app.SamplingperiodEditField.Visible = false;
            end
            % Intialize OP
            app.x_op = ones(sysdef.nx,1);
            app.th_op = ones(sysdef.nth,1);
            app.w_op = ones(sysdef.nx,1)*0;
            app.u_op = ones(max(sysdef.nu,1),1);
            app.e_op = zeros(max(sysdef.ny,1),1);
            
            app.OPStateEditField.Value = mat2str(app.x_op);
            app.OPParameterEditField.Value = mat2str(app.th_op);
            app.OPProcNoiseEditField.Value = mat2str(app.w_op);
            app.OPInputEditField.Value = mat2str(app.u_op);
            
            % Write fixed text
            app.DimensionsTextArea.Value = mat2strmat([sysdef.nx,sysdef.nth,sysdef.nu,sysdef.ny]);
            
            % Configuration
            app.sysopt = sysopt;
            app.InitialstateEditField.Value = mat2str(sysopt.x0);
            app.ExpectedvalueEditField.Value = mat2str(sysopt.th0);
            app.InitialcovarianceEditField.Value = mat2str(sysopt.P0);
            app.InitialoutputEditField.Value = mat2str(sysopt.y0);
            app.ProcessnoisecovarianceEditField.Value = mat2str(sysopt.Q);
            app.MeasurementnoisecovarianceEditField.Value = mat2str(sysopt.R);
            app.LowerboundEditField.Value = mat2str(sysopt.thBounds(:,1));
            app.UpperboundEditField.Value = mat2str(sysopt.thBounds(:,2));
        end
        
        
        function evalJacobians(app)
            
            % Check inputs
            FieldTable = table([],[],'VariableNames',{'FieldName','DefaultValue'});
            FieldTable = [FieldTable; {"OPStateEditField",{app.x_op}}];
            FieldTable = [FieldTable; {"OPInputEditField",{app.u_op}}];
            FieldTable = [FieldTable; {"OPParameterEditField",{app.th_op}}];
            FieldTable = [FieldTable; {"OPProcNoiseEditField",{app.w_op}}];
            
            for i = 1:size(FieldTable,1)
                fieldok = changeEditFieldValue(app,FieldTable.FieldName(i),FieldTable.DefaultValue{i});
                if ~fieldok
                    errordlg(sprintf('Invalid value in %s',FieldTable.FieldName(i)),'Edit Field Value Error');
                    return;
                end
            end
            
            app.x_op = eval(app.OPStateEditField.Value);
            app.u_op = eval(app.OPInputEditField.Value);
            app.th_op = eval(app.OPParameterEditField.Value);
            app.w_op = eval(app.OPProcNoiseEditField.Value);
            
            dfd_dx = full(genmod('dfddx',0,app.x_op,app.u_op,app.th_op,app.w_op));
            dfd_dw = full(genmod('dfddw',0,app.x_op,app.u_op,app.th_op,app.w_op));
            dfd_dth = full(genmod('dfddth',0,app.x_op,app.u_op,app.th_op,app.w_op));
            dg_dx = full(genmod('dgdx',0,app.x_op,app.u_op,app.th_op,app.e_op));
            
            app.dfddxTextArea.Value = mat2strmat(dfd_dx);
            app.dfddwTextArea.Value = mat2strmat(dfd_dw);
            app.dfddthTextArea.Value = mat2strmat(dfd_dth);
            app.dgdxTextArea.Value = mat2strmat(dg_dx);
            
        end
        
        
        function fieldok = changeEditFieldValue(app,EditFieldName,DefaultValue)
            try
                ChangingValue = eval(app.(EditFieldName).Value);
                if isa(ChangingValue,'numeric') ...
                        && any(size(ChangingValue) ~= size(DefaultValue))
                    app.(EditFieldName).Value = mat2str(DefaultValue);
                    fieldok = false;
                else
                    fieldok = true;
                end
            catch
                app.(EditFieldName).Value = mat2str(DefaultValue);
                fieldok = false;
            end
        end
        
        function saveConfig(app)
            
            % Check inputs
            FieldTable = table([],[],'VariableNames',{'FieldName','DefaultValue'});
            FieldTable = [FieldTable; {"InitialoutputEditField",{app.sysopt.y0}}];
            FieldTable = [FieldTable; {"InitialstateEditField",{app.sysopt.x0}}];
            FieldTable = [FieldTable; {"InitialcovarianceEditField",{app.sysopt.P0}}];
            FieldTable = [FieldTable; {"ProcessnoisecovarianceEditField",{app.sysopt.Q}}];
            FieldTable = [FieldTable; {"MeasurementnoisecovarianceEditField",{app.sysopt.R}}];
            FieldTable = [FieldTable; {"LowerboundEditField",{app.sysopt.thBounds(:,1)}}];
            FieldTable = [FieldTable; {"UpperboundEditField",{app.sysopt.thBounds(:,2)}}];
            FieldTable = [FieldTable; {"ExpectedvalueEditField",{app.sysopt.th0}}];
            
            for i = 1:size(FieldTable,1)
                fieldok = changeEditFieldValue(app,FieldTable.FieldName(i),FieldTable.DefaultValue{i});
                if ~fieldok
                    errordlg(sprintf('Invalid value in %s',FieldTable.FieldName(i)),'Edit Field Value Error');
                    return;
                end
            end
            
            % Save configuration locally
            app.sysopt.y0 = eval(app.InitialoutputEditField.Value);
            app.sysopt.x0 = eval(app.InitialstateEditField.Value);
            app.sysopt.P0 = eval(app.InitialcovarianceEditField.Value);
            app.sysopt.Q = eval(app.ProcessnoisecovarianceEditField.Value);
            app.sysopt.R = eval(app.MeasurementnoisecovarianceEditField.Value);
            app.sysopt.thBounds(:,1) = eval(app.LowerboundEditField.Value);
            app.sysopt.thBounds(:,2) = eval(app.UpperboundEditField.Value);
            app.sysopt.th0 = eval(app.ExpectedvalueEditField.Value);
            app.sysopt.Ts = app.SamplingperiodEditField.Value; % numeric edit field
            
            % save configuation to main app
            DefId = app.CallingApp.SystemDropDown.Value;
            app.CallingApp.SysOpt{DefId} = app.sysopt;
            
            msgbox('Configuration saved','Save','help');
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, sysdef, sysopt)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            app.SystemOptionsUIFigure.Position(1:2) = [app.CallingApp.UIFigure.Position(1),...
                app.CallingApp.UIFigure.Position(2)+app.CallingApp.UIFigure.Position(4)-app.SystemOptionsUIFigure.Position(4)];
            
            % Update UI with input values
            initializeFields(app,sysdef,sysopt)
            
            % Show jacobians
            evalJacobians(app)
        end

        % Close request function: SystemOptionsUIFigure
        function SystemOptionsUIFigureCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            try
                app.CallingApp.EditSystemButton.Enable = 'on';
                app.CallingApp.AddSystemButton.Enable = 'on';
                app.CallingApp.SystemDropDown.Enable = 'on';
            catch 
            end
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveConfig(app);
        end

        % Button pushed function: RefreshJacobiansButton
        function RefreshJacobiansButtonPushed(app, event)
            evalJacobians(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SystemOptionsUIFigure = uifigure('Visible', 'off');
            app.SystemOptionsUIFigure.Name = 'System Options';
            app.SystemOptionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @SystemOptionsUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.SystemOptionsUIFigure);
            app.CONFIGURATIONLabel = uilabel(app.GridLayout);
            app.CONFIGURATIONLabel.Text = 'CONFIGURATION';
            app.OVERVIEWLabel = uilabel(app.GridLayout);
            app.OVERVIEWLabel.Text = 'OVERVIEW';
            app.ConfigPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.ConfigPanel);
            app.InitialconditionsLabel = uilabel(app.GridLayout2);
            app.InitialconditionsLabel.Text = 'Initial conditions';
            app.InitialoutputEditFieldLabel = uilabel(app.GridLayout2);
            app.InitialoutputEditFieldLabel.Text = 'Initial output';
            app.InitialoutputEditField = uieditfield(app.GridLayout2, 'text');
            app.InitialstateEditFieldLabel = uilabel(app.GridLayout2);
            app.InitialstateEditFieldLabel.Text = 'Initial state';
            app.InitialstateEditField = uieditfield(app.GridLayout2, 'text');
            app.InitialcovarianceEditFieldLabel = uilabel(app.GridLayout2);
            app.InitialcovarianceEditFieldLabel.Text = 'Initial covariance';
            app.InitialcovarianceEditField = uieditfield(app.GridLayout2, 'text');
            app.NoiseLabel = uilabel(app.GridLayout2);
            app.NoiseLabel.Text = 'Noise';
            app.ProcessnoisecovarianceEditFieldLabel = uilabel(app.GridLayout2);
            app.ProcessnoisecovarianceEditFieldLabel.Text = 'Process noise covariance';
            app.ProcessnoisecovarianceEditField = uieditfield(app.GridLayout2, 'text');
            app.MeasurementnoisecovarianceEditFieldLabel = uilabel(app.GridLayout2);
            app.MeasurementnoisecovarianceEditFieldLabel.Text = 'Measurement noise covariance';
            app.MeasurementnoisecovarianceEditField = uieditfield(app.GridLayout2, 'text');
            app.ParameterLabel = uilabel(app.GridLayout2);
            app.ParameterLabel.Text = 'Parameter';
            app.LowerboundEditFieldLabel = uilabel(app.GridLayout2);
            app.LowerboundEditFieldLabel.Text = 'Lower bound';
            app.LowerboundEditField = uieditfield(app.GridLayout2, 'text');
            app.UpperboundEditFieldLabel = uilabel(app.GridLayout2);
            app.UpperboundEditFieldLabel.Text = 'Upper bound';
            app.UpperboundEditField = uieditfield(app.GridLayout2, 'text');
            app.ExpectedvalueEditFieldLabel = uilabel(app.GridLayout2);
            app.ExpectedvalueEditFieldLabel.Text = 'Expected value';
            app.ExpectedvalueEditField = uieditfield(app.GridLayout2, 'text');
            app.SamplingperiodEditFieldLabel = uilabel(app.GridLayout2);
            app.SamplingperiodEditFieldLabel.Text = 'Sampling period';
            app.SamplingperiodEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SamplingperiodEditField.Limits = [1e-10 Inf];
            app.SamplingperiodEditField.Value = 1;
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OverviewPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.OverviewPanel);
            app.dfddxTextAreaLabel = uilabel(app.GridLayout3);
            app.dfddxTextAreaLabel.Text = 'dfd/dx';
            app.dfddxTextArea = uitextarea(app.GridLayout3);
            app.dfddxTextArea.Editable = 'off';
            app.OPStateEditFieldLabel = uilabel(app.GridLayout3);
            app.OPStateEditFieldLabel.Text = 'OP State';
            app.OPStateEditField = uieditfield(app.GridLayout3, 'text');
            app.DimensionsstateparameterinputoutputLabel = uilabel(app.GridLayout3);
            app.DimensionsstateparameterinputoutputLabel.Text = 'Dimensions (state, parameter, input, output)';
            app.DimensionsTextArea = uitextarea(app.GridLayout3);
            app.DimensionsTextArea.Editable = 'off';
            app.dfddthTextAreaLabel = uilabel(app.GridLayout3);
            app.dfddthTextAreaLabel.Text = 'dfd/dth';
            app.dfddthTextArea = uitextarea(app.GridLayout3);
            app.dfddthTextArea.Editable = 'off';
            app.dfddwTextAreaLabel = uilabel(app.GridLayout3);
            app.dfddwTextAreaLabel.Text = 'dfd/dw';
            app.dfddwTextArea = uitextarea(app.GridLayout3);
            app.dfddwTextArea.Editable = 'off';
            app.OPParameterEditFieldLabel = uilabel(app.GridLayout3);
            app.OPParameterEditFieldLabel.Text = 'OP Parameter';
            app.OPParameterEditField = uieditfield(app.GridLayout3, 'text');
            app.OPInputEditFieldLabel = uilabel(app.GridLayout3);
            app.OPInputEditFieldLabel.Text = 'OP Input';
            app.OPInputEditField = uieditfield(app.GridLayout3, 'text');
            app.OPProcNoiseEditFieldLabel = uilabel(app.GridLayout3);
            app.OPProcNoiseEditFieldLabel.Text = 'OP Proc. Noise';
            app.OPProcNoiseEditField = uieditfield(app.GridLayout3, 'text');
            app.dgdxTextAreaLabel = uilabel(app.GridLayout3);
            app.dgdxTextAreaLabel.Text = 'dg/dx';
            app.dgdxTextArea = uitextarea(app.GridLayout3);
            app.dgdxTextArea.Editable = 'off';
            app.dgdthTextAreaLabel = uilabel(app.GridLayout3);
            app.dgdthTextAreaLabel.Text = 'dg/dth';
            app.dgdthTextArea = uitextarea(app.GridLayout3);
            app.dgdthTextArea.Editable = 'off';
            app.RefreshJacobiansButton = uibutton(app.GridLayout, 'push');
            app.RefreshJacobiansButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshJacobiansButtonPushed, true);
            app.RefreshJacobiansButton.Text = 'Refresh Jacobians';
            % Show the figure after all components are created
            app.SystemOptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sesupOptions(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SystemOptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SystemOptionsUIFigure)
        end
    end
end